classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        StartButton             matlab.ui.control.Button
        PlotmaxreachCheckBox    matlab.ui.control.CheckBox
        PlotpointcloudCheckBox  matlab.ui.control.CheckBox
        ContinueButton          matlab.ui.control.Button
        XEditFieldLabel         matlab.ui.control.Label
        XEditField              matlab.ui.control.NumericEditField
        RobotArm1Label          matlab.ui.control.Label
        YEditFieldLabel         matlab.ui.control.Label
        YEditField              matlab.ui.control.NumericEditField
        ZEditFieldLabel         matlab.ui.control.Label
        ZEditField              matlab.ui.control.NumericEditField
        RobotArm2Label          matlab.ui.control.Label
        XEditField_2Label       matlab.ui.control.Label
        XEditField_2            matlab.ui.control.NumericEditField
        YEditField_2Label       matlab.ui.control.Label
        YEditField_2            matlab.ui.control.NumericEditField
        ZEditField_2Label       matlab.ui.control.Label
        ZEditField_2            matlab.ui.control.NumericEditField
        HousingTopLabel         matlab.ui.control.Label
        XEditField_3Label       matlab.ui.control.Label
        XEditField_3            matlab.ui.control.NumericEditField
        YEditField_3Label       matlab.ui.control.Label
        YEditField_3            matlab.ui.control.NumericEditField
        ZEditField_3Label       matlab.ui.control.Label
        ZEditField_3            matlab.ui.control.NumericEditField
        HousingBottomLabel      matlab.ui.control.Label
        XEditField_4Label       matlab.ui.control.Label
        XEditField_4            matlab.ui.control.NumericEditField
        YEditField_4Label       matlab.ui.control.Label
        YEditField_4            matlab.ui.control.NumericEditField
        ZEditField_4Label       matlab.ui.control.Label
        ZEditField_4            matlab.ui.control.NumericEditField
        CircuitBoardLabel       matlab.ui.control.Label
        XEditField_5Label       matlab.ui.control.Label
        XEditField_5            matlab.ui.control.NumericEditField
        YEditField_5Label       matlab.ui.control.Label
        YEditField_5            matlab.ui.control.NumericEditField
        ZEditField_5Label       matlab.ui.control.Label
        ZEditField_5            matlab.ui.control.NumericEditField
        Button                  matlab.ui.control.StateButton
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        workSize = 0.6;
        workspace = [-1 1 -1 1 -1 1];
        scale = 0;
        
        x = 0.4;
        y = 0.2;
        z = 0;
    end
    
    methods (Access = public)
    end
    
    methods (Access = private)
        
        
        function results = setup(app)
            % import arms
            UR3_1 = UR3Model('UR3_1',app.workspace, transl(app.x,app.y,app.z), 1);
            UR3_2 = UR3Model('UR3_2',app.workspace, transl(-0.1,-0.05,app.z), 1);
            
            % import objects
            % fix translations
            % Table = Objects('table','1',app.workspace,transl(x,y,z));
            % Fence1 = Objects('fence','1',app.workspace,transl(x,y,z));
            % Fence2 = Objects('fence','2',app.workspace,transl(x,y,z) * trotz(pi/2));
            % Fence3 = Objects('fence','3',app.workspace,transl(2*x,y,z) * trotz(pi));
            % Fence4 = Objects('fence','4',app.workspace,transl(2*x,y,z) * trotz((3 *pi)/2));
            HousingTop = Objects('housingTop','1',app.workspace,transl(1,app.y,app.z));
            HousingBottom = Objects('housingBottom','1',app.workspace,transl(0.5,app.y,app.z));
            CircuitBoard = Objects('circuitBoard','1',app.workspace,transl(0,app.y,app.z));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.setup();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PlotmaxreachCheckBox = uicheckbox(app.UIFigure);
            app.PlotmaxreachCheckBox.Text = 'Plot max reach';
            app.PlotpointcloudCheckBox = uicheckbox(app.UIFigure);
            app.PlotpointcloudCheckBox.Text = 'Plot point cloud';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.Text = 'Continue';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.RobotArm1Label = uilabel(app.UIFigure);
            app.RobotArm1Label.Text = 'Robot Arm 1';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.RobotArm2Label = uilabel(app.UIFigure);
            app.RobotArm2Label.Text = 'Robot Arm 2';
            app.XEditField_2Label = uilabel(app.UIFigure);
            app.XEditField_2Label.Text = 'X';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2Label = uilabel(app.UIFigure);
            app.YEditField_2Label.Text = 'Y';
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_2Label = uilabel(app.UIFigure);
            app.ZEditField_2Label.Text = 'Z';
            app.ZEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HousingTopLabel = uilabel(app.UIFigure);
            app.HousingTopLabel.Text = 'Housing Top';
            app.XEditField_3Label = uilabel(app.UIFigure);
            app.XEditField_3Label.Text = 'X';
            app.XEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_3Label = uilabel(app.UIFigure);
            app.YEditField_3Label.Text = 'Y';
            app.YEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_3Label = uilabel(app.UIFigure);
            app.ZEditField_3Label.Text = 'Z';
            app.ZEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.HousingBottomLabel = uilabel(app.UIFigure);
            app.HousingBottomLabel.Text = 'Housing Bottom';
            app.XEditField_4Label = uilabel(app.UIFigure);
            app.XEditField_4Label.Text = 'X';
            app.XEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_4Label = uilabel(app.UIFigure);
            app.YEditField_4Label.Text = 'Y';
            app.YEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_4Label = uilabel(app.UIFigure);
            app.ZEditField_4Label.Text = 'Z';
            app.ZEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.CircuitBoardLabel = uilabel(app.UIFigure);
            app.CircuitBoardLabel.Text = 'Circuit Board';
            app.XEditField_5Label = uilabel(app.UIFigure);
            app.XEditField_5Label.Text = 'X';
            app.XEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_5Label = uilabel(app.UIFigure);
            app.YEditField_5Label.Text = 'Y';
            app.YEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField_5Label = uilabel(app.UIFigure);
            app.ZEditField_5Label.Text = 'Z';
            app.ZEditField_5 = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.Text = 'Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end